classdef MiApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        axes                        matlab.ui.control.UIAxes
        b1                          matlab.ui.control.Button
        b2                          matlab.ui.control.Button
        IdentificadordeplantaLabel  matlab.ui.control.Label
        PuertoLabel                 matlab.ui.control.Label
        status                      matlab.ui.control.Label
        Exportar                    matlab.ui.control.StateButton
        EstadoLabel                 matlab.ui.control.Label
        puerto                      matlab.ui.control.Label
        aviso                       matlab.ui.control.Label
        SetPointEditFieldLabel      matlab.ui.control.Label
        sp                          matlab.ui.control.EditField
        Enviar                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        s; % Description 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.s = serialport("COM3",115200,"Timeout",5);
            app.puerto.Text = serialportlist;
        end

        % Button pushed function: b1
        function b1ButtonPushed(app, event)
          global parar datos  
          cla(app.axes,"reset");
          app.aviso.Visible = false;
          app.axes.XGrid = true;
          app.axes.YGrid = true;
          parar = true;
          k = 1;
          dt = 0.1;
          app.status.Text = "Encendido";
          while parar 
                vector_setpoint(k) =  str2double(app.sp.Value); 
                tiempo(k) = k;
                valor(k) = str2double(readline(app.s));
                plot(app.axes,tiempo,valor,tiempo,vector_setpoint);
                app.axes.XLim = [0 k+50];
                app.axes.YLim = [-10 10];
                datos = [valor;tiempo;vector_setpoint;tiempo];
                k = k + 1;
                pause(dt)
          end
          
        end

        % Button pushed function: b2
        function b2ButtonPushed(app, event)
                global parar
                parar = false;
                app.status.Text = "Apagado";
        end

        % Value changed function: Exportar
        function ExportarValueChanged(app, event)
            global datos
            writematrix(datos','datos.txt');
            app.aviso.Visible = true;
        end

        % Button pushed function: Enviar
        function EnviarButtonPushed(app, event)
            setpoint = app.sp.Value;
            writeline(app.s,setpoint);
        end

        % Value changed function: sp
        function spValueChanged(app, event)
            value = app.sp.Value;
            writeline(app.s,value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.axes = uiaxes(app.UIFigure);
            title(app.axes, '')
            xlabel(app.axes, 'Tiempo')
            ylabel(app.axes, {'Valor'; ''})
            app.axes.XLim = [0 5];
            app.axes.YLim = [-5 5];
            app.b1 = uibutton(app.UIFigure, 'push');
            app.b1.ButtonPushedFcn = createCallbackFcn(app, @b1ButtonPushed, true);
            app.b1.Text = {'Iniciar'; ''};
            app.b2 = uibutton(app.UIFigure, 'push');
            app.b2.ButtonPushedFcn = createCallbackFcn(app, @b2ButtonPushed, true);
            app.b2.Text = {'Parar'; ''};
            app.IdentificadordeplantaLabel = uilabel(app.UIFigure);
            app.IdentificadordeplantaLabel.Text = 'Identificador de planta ';
            app.PuertoLabel = uilabel(app.UIFigure);
            app.PuertoLabel.Text = 'Puerto: ';
            app.status = uilabel(app.UIFigure);
            app.status.Text = 'Apagado';
            app.Exportar = uibutton(app.UIFigure, 'state');
            app.Exportar.ValueChangedFcn = createCallbackFcn(app, @ExportarValueChanged, true);
            app.Exportar.Text = 'Exportar';
            app.EstadoLabel = uilabel(app.UIFigure);
            app.EstadoLabel.Text = 'Estado :';
            app.puerto = uilabel(app.UIFigure);
            app.puerto.Text = '...';
            app.aviso = uilabel(app.UIFigure);
            app.aviso.Visible = 'off';
            app.aviso.Text = 'Datos exportados';
            app.SetPointEditFieldLabel = uilabel(app.UIFigure);
            app.SetPointEditFieldLabel.Text = 'Set Point';
            app.sp = uieditfield(app.UIFigure, 'text');
            app.sp.ValueChangedFcn = createCallbackFcn(app, @spValueChanged, true);
            app.sp.Value = '0.0';
            app.Enviar = uibutton(app.UIFigure, 'push');
            app.Enviar.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.Enviar.Text = 'Enviar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MiApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end